classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TabGroup        matlab.ui.container.TabGroup
        locationTab     matlab.ui.container.Tab
        locationFigure  matlab.ui.control.UIAxes
        areaTab         matlab.ui.container.Tab
        areaFigure      matlab.ui.control.UIAxes
        blueSet         matlab.ui.container.Panel
        blueUAVs        matlab.ui.control.ListBox
        Label           matlab.ui.control.Label
        blueSelect      matlab.ui.control.DropDown
        addBlueUAV      matlab.ui.control.Button
        delBlueUAV      matlab.ui.control.Button
        xLabel          matlab.ui.control.Label
        blueXInput      matlab.ui.control.NumericEditField
        yLabel          matlab.ui.control.Label
        blueYInput      matlab.ui.control.NumericEditField
        start           matlab.ui.control.Button
        redSet          matlab.ui.container.Panel
        redUAVs         matlab.ui.control.ListBox
        Label_2         matlab.ui.control.Label
        redSelect       matlab.ui.control.DropDown
        addRedUAV       matlab.ui.control.Button
        delRedUAV       matlab.ui.control.Button
        xLabel_2        matlab.ui.control.Label
        redXInput       matlab.ui.control.NumericEditField
        yLabel_2        matlab.ui.control.Label
        redYInput       matlab.ui.control.NumericEditField
        areaSet         matlab.ui.container.Panel
        Label_3         matlab.ui.control.Label
        widthInput      matlab.ui.control.NumericEditField
        Label_4         matlab.ui.control.Label
        heightInput     matlab.ui.control.NumericEditField
        Label_5         matlab.ui.control.Label
        girdInput       matlab.ui.control.NumericEditField
        Panel           matlab.ui.container.Panel
        TextArea        matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        BTemp % Description
        V
        R% Description
        L
        M
        Gird% Description
        A
        B% Description
    end
    
    methods (Access = private)
        %% 根据输入区域大小设置XY的上下界
        function setLimits(app, type, value)
            if type == 'w'
                app.blueXInput.Limits(2) = value;
                app.redXInput.Limits(2) = value;
                type = 1;
            elseif type == 'h'
                app.blueYInput.Limits(2) = value;
                app.redYInput.Limits(2) = value;
                type = 2;
            else
                msgbox('Limits type wrong.', 'Error','error');
            end
            app.redUAVs.Value = {};
            j = 1;
            for i=1:size(app.redUAVs.Items, 2)
                [xy(1), xy(2)] = getXY(app, app.redUAVs.Items(j));
                if xy(type) > value
                    app.redUAVs.Items(j) = [];
                else
                    j = j + 1;
                end
            end
            app.blueUAVs.Value = {};
            j = 1;
            for i=1:size(app.redUAVs.Items, 2)
                [xy(1), xy(2)]= getXY(app, app.blueUAVs.Items(j));
                if xy(type) > value
                    app.blueUAVs.Items(j) = [];
                else
                    j = j + 1;
                end
            end
        end
        
        %% 获取输入的XY
        function [x, y] = getXY(app, c)
            str1 = strsplit(cell2mat(c), {',','(',')'});
            x = str2double(char(str1(2)));
            y = str2double(char(str1(3)));
        end
        
        %% 输入检查
        function result = checkLimit(app, type, x, y, s)
            if type == 'r'
                a = app.redUAVs.Items;
            elseif type == 'b'
                a = app.blueUAVs.Items;
            else
                msgbox('Limits type wrong.', 'Error','error');
            end
            
            x_max = app.widthInput.Value;
            y_max = app.heightInput.Value;
            
            if (x < 0) || (x > x_max) || (y < 0) || (y > y_max)
                msgbox('坐标超出攻击范围。', 'Error','error');
            end
            for i = 1:s
                [x_in_list, y_in_list] = getXY(app, a(i));
                if x == x_in_list && y == y_in_list
                    msgbox('已有相同坐标点。', 'Error','error');
                    result = 0;
                    return
                end
            end
            result = 1;
        end
        
        %% 删除UAV
        function delUAV(app, type)
            if type == 'r'
                a = app.redUAVs;
            elseif type == 'b'
                a = app.blueUAVs;
            else
                msgbox('Limits type wrong.', 'Error','error');
            end
            
            j = 1;
            for i = 1:size(a.Items, 2)
                if char(a.Items(j)) == char(a.Value)
                    result = j;
                else
                    j = j + 1;
                end
            end
            
            if result == 0
                msgbox('something wrong.', 'Error','error');
                return
            end
            
            if type == 'r'
                app.redUAVs.Items(result) = [];
            elseif type == 'b'
                app.blueUAVs.Items(result) = [];
            end
        end
        
        %% 仿真
        function results = simulationFunc(app, p_b, p_r, DrawPicture)
            % 初始化
            numB = size(p_b, 1);
            numR = size(p_r, 1);
            p_bt = zeros(numB, 360, 4);
            p_rt = zeros(numR, 360, 4);
            app.BTemp = [0, 0];
            results = 0;
            for i = 1:numB
                p_bt(i, 1, 1:4) = [p_b(i, 1), p_b(i, 2), 1, 0];
            end
            for i = 1:numR
                p_rt(i, 1, 1:4) = [p_r(i, 1), p_r(i, 2), -1, 0];
            end
            %仿真
            for t = 1:359
                numB_t = numB;
                for i = 1:numB
                    if(InterceptSuccess(app, p_bt(i, t, :), p_rt(1, t, :),  p_rt(2, t, :)))
                        numB_t = numB_t - 1;
                    elseif(BreakthroughSuccess(app, p_bt(i, t, :)))
                        numB_t = numB_t - 1;
                        results = 1;
                        break;
                    end
                end
                
                if numB_t==0
                    break;
                end
                
                app.R = 350;
                app.V = 200;
                switch str2double(app.redSelect.Value)
                    case 1
                        for i = 1:numR
                            [p_rt(i, t+1, 1), p_rt(i, t+1, 2), p_rt(i, t+1 ,3), p_rt(i, t+1, 4)] = RefreshRedPosition1(app, p_bt(1, t, :), p_rt(i, t, :));
                        end
                    case 2
                        for i = 1:numR
                            [p_rt(i, t+1, 1), p_rt(i, t+1, 2), p_rt(i, t+1 ,3), p_rt(i, t+1, 4)] = RefreshRedPosition2(app, p_bt(1, t, :), p_rt(i, t, :));
                        end
                    case 3
                        for i = 1:numR
                            [p_rt(i, t+1, 1), p_rt(i, t+1, 2), p_rt(i, t+1 ,3), p_rt(i, t+1, 4)] = RefreshRedPosition3(app, p_bt(1, t, :), p_rt(i, t, :));
                        end
                    otherwise
                        msgbox('No such Function.', 'Error','error');
                end
                app.R = 500;
                app.V = 250;
                switch str2double(app.blueSelect.Value)
                    case 1
                        for i = 1:numB
                            [p_bt(i, t+1, 1), p_bt(i, t+1, 2), p_bt(i, t+1 ,3), p_bt(i, t+1, 4)] = RefreshBluePosition1(app, p_bt(i, t, :));
                        end
                    case 2
                        for i = 1:numB
                            [p_bt(i, t+1, 1), p_bt(i, t+1, 2), p_bt(i, t+1 ,3), p_bt(i, t+1, 4)] = RefreshBluePosition2(app, p_bt(i, t, :), p_rt, t);
                        end
                    otherwise
                        msgbox('No such Function.', 'Error','error');
                end
            end
             %画图
                if DrawPicture == 1
                    hold(app.locationFigure, 'on');
                    for i = 1:numR
                        plot(app.locationFigure, p_rt(i, :, 1), p_rt(i, :, 2), 'r.');
                    end
                    
                    for i = 1:numB
                        plot(app.locationFigure, p_bt(i, :, 1), p_bt(i, :, 2), 'b.');
                    end
                end
        end
        
        %% 区域遍历
        function areaFunc(app, p_r)
            result = zeros(app.L/app.Gird, app.M/app.Gird);
            f = waitbar(0,'1','Name','Calculating Avaliable Areas ...',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            for x_s = app.Gird:app.Gird:app.L
                for y_s = app.Gird:app.Gird:app.M
                    if(simulationFunc(app, [x_s-0.5*app.Gird, y_s-0.5*app.Gird], p_r, 0))
                        result(x_s/app.Gird, y_s/app.Gird) = 1;
                    end
                    waitbar((app.M*(x_s-app.Gird)+y_s*app.Gird)/(app.L*app.M), f, sprintf('%12.2f',(app.M*(x_s-app.Gird)+y_s*app.Gird)/(app.L*app.M)))
                end
            end
            
            delete(f)
            
            %%画图
            f = waitbar(0,'1','Name','Calculating Avaliable Areas ...',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            hold(app.areaFigure, 'on')
            rectangle(app.areaFigure, 'Position', [0,0,app.L,app.M], 'EdgeColor', 'b', 'FaceColor','b');
            for x_s = app.Gird:app.Gird:app.L
                for y_s = app.Gird:app.Gird:app.M
                    if(result(x_s/app.Gird, y_s/app.Gird) == 0)
                        rectangle(app.areaFigure, 'Position', [x_s-app.Gird, y_s-app.Gird, app.Gird, app.Gird], 'EdgeColor', 'r', 'FaceColor','r');
                    end
                    waitbar((app.M*(x_s-app.Gird)+y_s*app.Gird)/(app.L*app.M), f, sprintf('%12.2f',(app.M*(x_s-app.Gird)+y_s*app.Gird)/(app.L*app.M)))
                end
            end
            delete(f)
        end
        
        %% 蓝方无人机策略,直飞
        function [x, y, x1, y1] = RefreshBluePosition1(app, p_b)
            x = p_b(1) + app.V;
            y = p_b(2);
            x1 = x - p_b(1);
            y1 = y - p_b(2);
            x1 = x1/norm([x1,y1]);
            y1 = y1/norm([x1,y1]);
        end
        
        %% 蓝方无人机策略，判断CG1、DG2
        function [x, y, x1, y1] = RefreshBluePosition2(app, p_b, p_r1, p_r2, t)
            if  min(dist(p_b(1:2), p_r1(t,1:2)'), dist(p_b(1:2), p_r2(t,1:2)'))>10000
                p_r = (p_r1(t,1:2) + p_r2(t,1:2))./2;
                [x, y, x1, y1] = R2B(app, p_b, p_r);
                return
            end
            
            if  p_b(1)>p_r1(t,1)&&p_b(1)>p_r2(t,1)
                [x, y, x1, y1] = RefreshBluePosition1(app, p_b, p_r1, p_r2, t);
                return
            end
            
            if app.BTemp(1) == 0
                d1 = dist(p_r1(t,1:2), [app.L, app.M]');
                d2 = dist(p_r2(t,1:2), [app.L, 0]');
                if d1<d2
                    app.BTemp = [app.L, 0];
                elseif d1>d2
                    app.BTemp = [app.L, app.M];
                else
                    if abs(p_b(2)-p_r1(t, 2)) > abs(p_b(2)-p_r2(t, 2))
                        app.BTemp = [app.L, 0];
                    else
                        app.BTemp = [app.L, app.M];
                    end
                end
            end
            [x, y, x1, y1] = R2B(app, p_b, app.BTemp);
            
        end
        
        %% 红方无人机策略，ABC换为曲线，飞行路径为圆弧
        function [x, y, a, b] = RefreshRedPosition1(app, p_b, p_r)
            [x, y, a, b] = R2B(app, p_r, p_b);
        end
        
        %% 红方无人机策略，ABC换为曲线，飞行路径为圆弧
        function [x, y, a, b] = RefreshRedPosition2(app, p_b, p_r)
            p_b(1) = app.L;
            [x, y, a, b] = R2B(app, p_r, p_b);
        end
        
        %% 红方无人机策略，ABC换为曲线，飞行路径为圆弧
        function [x, y, a, b] = RefreshRedPosition3(app, p_b, p_r)
            if abs(p_r(1)-p_b(1))>10000 %距离大于10000预测飞行，小于10000径直拦截
                p_b(1) = app.L;
            end
            [x, y, a, b] = R2B(app, p_r, p_b);
        end
        %% 迭代下一个点
        function [x, y, a, b] = R2B(app, p_r, p_b)
            p_r = p_r(:)';
            p_b = p_b(:)';
            theta = app.V/app.R;
            
            p_b(1:2) = p_b(1:2) - p_r(1:2);
            [p_b(1), p_b(2)] = Rotate(app, p_b(1:2), [0, 0], [p_r(3),p_r(4)].*[1,-1]);
            k2 = sign(p_b(1));
            
            if p_b(1) == 0
                k = sign(p_b(2))*k2;
                if k==0
                    k=k2;
                end
                [x, y] = Rotate(app, p_r(1:2) + [app.R*sin(theta), k*(app.R-app.R*cos(theta))], p_r(1:2), p_r(3:4));
                [a, b] = Rotate(app, p_r(3:4), [0, 0], [cos(k*theta), sin(k*theta)]);
            elseif  abs(p_b(2)/p_b(1))< 0.001 && k2>0
                x = p_r(1) + app.V*p_r(3);
                y = p_r(2) + app.V*p_r(4);
                a = p_r(3);
                b = p_r(4);
            elseif abs(p_b(2)/p_b(1)) <= ((app.R-app.R*cos(theta))/(app.R*sin(theta))) && k2>0
                [x,y,a,b] = GetPoint(app, p_b, p_r, sign(p_b(2)/p_b(1)));
            else
                k = sign(p_b(2)/p_b(1))*k2;
                if k==0
                    k=1*k2;
                end
                [x, y] = Rotate(app, p_r(1:2) + [app.R*sin(theta), k*(app.R-app.R*cos(theta))], p_r(1:2), p_r(3:4));
                [a, b] = Rotate(app, p_r(3:4), [0, 0], [cos(k*theta), sin(k*theta)]);
            end
            
        end
        
        %% 拦截条件
        function result = InterceptSuccess(app, p_b, p_r1, p_r2)
            p_b = p_b(:);
            p_r(1,1:2) = [p_r1(1), p_r1(2) + 100/sin(pi/5)];
            p_r(2,1:2) = [p_r1(1)-100, p_r1(2)-100/tan(pi/5)];
            p_r(3,1:2) = [p_r1(1)+100, p_r1(2)-100/tan(pi/5)];
            p_r(4,1:2) = [p_r1(1)-200*sin(2*pi/5), p_r1(2)+100/sin(pi/5)-100*cos(2*pi/5)];
            p_r(5,1:2) = [p_r1(1)+200*sin(2*pi/5), p_r1(2)+100/sin(pi/5)-100*cos(2*pi/5)];
            p_r(6,1:2) = [p_r2(1), p_r2(2) + 100/sin(pi/5)];
            p_r(7,1:2) = [p_r2(1)-100, p_r2(2)-100/tan(pi/5)];
            p_r(8,1:2) = [p_r2(1)+100, p_r2(2)-100/tan(pi/5)];
            p_r(9,1:2) = [p_r2(1)-200*sin(2*pi/5), p_r2(2)+100/sin(pi/5)-100*cos(2*pi/5)];
            p_r(10,1:2) = [p_r2(1)+200*sin(2*pi/5), p_r2(2)+100/sin(pi/5)-100*cos(2*pi/5)];
            count = 0;
            % 计数
            for i = 1:10
                count = count + (dist(p_r(i,1:2),p_b(1:2))<=300);
            end
            
            if (count<2 || p_b(1)>app.L || p_b(2)<0 || p_b(1)<0)
                result = 0;
            else
                result = 1;
            end
        end
        %% 突防条件
        function result = BreakthroughSuccess(app, p_b)
            if p_b(1)>app.L
                result = 1;
            else
                result = 0;
            end
        end
        %% 获取ABC上的点
        function [x, y, a, b] = GetPoint(app, p_b, p_r, t)
            app.A = p_b(1);
            app.B = p_b(2);
            if t == 1
                z_r = fzero(@(z) z.*sin(app.V./z)/app.A-(z-z.*cos(app.V./z))/app.B, [app.R, 200000]);
            else
                z_r = fzero(@(z) z.*sin(app.V./z)/app.A+(z-z.*cos(app.V./z))/app.B, [app.R, 200000]);
            end
            x = z_r*sin(app.V/z_r);
            y = t * (z_r-z_r*cos(app.V/z_r));
            a = (z_r - abs(y))/z_r;
            b = t*x/z_r;
            [x, y] = Rotate(app, [x, y], [0, 0],p_r(3:4));
            [a, b] = Rotate(app, [a, b], [0, 0],p_r(3:4));
            x = x + p_r(1);
            y = y + p_r(2);
        end
        
        %% 旋转
        function [x, y] = Rotate(app, S, C, RO)
            x = (S(1) - C(1)).*RO(1) - (S(2) - C(2)).*RO(2) + C(1);
            y = (S(1) - C(1)).*RO(2) + (S(2) - C(2)).*RO(1) + C(2);
        end
        
    end
    

    methods (Access = private)

        % Value changed function: widthInput
        function AreaWidthValueChanged(app, event)
            value = app.widthInput.Value;
            setLimits(app, 'w', value);
        end

        % Value changed function: heightInput
        function AreaHeightValueChanged(app, event)
            value = app.heightInput.Value;
            setLimits(app, 'h', value);
        end

        % Button pushed function: addBlueUAV
        function addBlueUAVButtonPushed(app, event)
            x = app.blueXInput.Value;
            y = app.blueYInput.Value;
            s = size(app.blueUAVs.Items, 2);
            if checkLimit(app, 'b', x, y, s)
                app.blueUAVs.Items(s+1) = cellstr(['(', num2str(x), ',', num2str(y), ')']);
            end
        end

        % Button pushed function: addRedUAV
        function addRedUAVButtonPushed(app, event)
            x = app.redXInput.Value;
            y = app.redYInput.Value;
            s = size(app.redUAVs.Items, 2);
            if checkLimit(app, 'r',x ,y ,s)
                app.redUAVs.Items(s+1) = cellstr(['(', num2str(x), ',', num2str(y), ')']);
            end
        end

        % Button pushed function: delBlueUAV
        function delBlueUAVButtonPushed(app, event)
            delUAV(app, 'b');
        end

        % Button pushed function: delRedUAV
        function delRedUAVButtonPushed(app, event)
            delUAV(app, 'r');
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            %% 输入检验
            if size(app.redUAVs.Items, 2) == 0
                msgbox('No red UAV.', 'Error','error');
                return
            end
            if size(app.blueUAVs.Items, 2) == 0
                msgbox('No blue UAV.', 'Error','error');
                return
            end
            if app.widthInput.Value < 1
                msgbox('Area width should be greater than 0.', 'Error','error')
                return
            end
            if app.heightInput.Value < 1
                msgbox('Area height should be greater than 0.', 'Error','error')
                return
            end
            %% 初始化
            cla(app.locationFigure,'reset');
            cla(app.areaFigure,'reset');
            app.L = app.widthInput.Value;
            app.M = app.heightInput.Value;
            app.Gird = app.girdInput.Value;
            p_b = zeros(size(app.blueUAVs.Items, 2), 2);
            for i = 1:size(app.blueUAVs.Items, 2)
                [p_b(i, 1), p_b(i, 2)] = getXY(app, app.blueUAVs.Items(i));
            end
            
            p_r = zeros(size(app.redUAVs.Items, 2), 2);
            for i = 1:size(app.redUAVs.Items, 2)
                [p_r(i, 1), p_r(i, 2)] = getXY(app, app.redUAVs.Items(i));
            end
            %% 仿真
            if app.TabGroup.SelectedTab == app.locationTab
                simulationFunc(app, p_b, p_r, 1);
            elseif app.TabGroup.SelectedTab == app.areaTab
                areaFunc(app, p_r);
            else
                msgbox('Something wrong.', 'Error','error');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '无人机协同对抗策略仿真平台';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.locationTab = uitab(app.TabGroup);
            app.locationTab.Title = '策略仿真';
            app.locationFigure = uiaxes(app.locationTab);
            app.areaTab = uitab(app.TabGroup);
            app.areaTab.Title = '对抗区域仿真';
            app.areaFigure = uiaxes(app.areaTab);
            app.blueSet = uipanel(app.UIFigure);
            app.blueSet.Title = '蓝方无人机';
            app.blueUAVs = uilistbox(app.blueSet);
            app.blueUAVs.Items = {'(0,0)'};
            app.blueUAVs.Value = '(0,0)';
            app.Label = uilabel(app.blueSet);
            app.Label.Text = '蓝方策略';
            app.blueSelect = uidropdown(app.blueSet);
            app.blueSelect.Items = {'策略一', '策略二'};
            app.blueSelect.ItemsData = {'1', '2'};
            app.blueSelect.Value = '1';
            app.addBlueUAV = uibutton(app.blueSet, 'push');
            app.addBlueUAV.ButtonPushedFcn = createCallbackFcn(app, @addBlueUAVButtonPushed, true);
            app.addBlueUAV.Text = '添加无人机';
            app.delBlueUAV = uibutton(app.blueSet, 'push');
            app.delBlueUAV.ButtonPushedFcn = createCallbackFcn(app, @delBlueUAVButtonPushed, true);
            app.delBlueUAV.Text = '删除选中';
            app.xLabel = uilabel(app.blueSet);
            app.xLabel.Text = 'x :';
            app.blueXInput = uieditfield(app.blueSet, 'numeric');
            app.blueXInput.Limits = [0 Inf];
            app.blueXInput.ValueDisplayFormat = '%.0f';
            app.yLabel = uilabel(app.blueSet);
            app.yLabel.Text = 'y :';
            app.blueYInput = uieditfield(app.blueSet, 'numeric');
            app.blueYInput.Limits = [0 Inf];
            app.blueYInput.ValueDisplayFormat = '%.0f';
            app.start = uibutton(app.UIFigure, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = '开始';
            app.redSet = uipanel(app.UIFigure);
            app.redSet.Title = '红方无人机';
            app.redUAVs = uilistbox(app.redSet);
            app.redUAVs.Items = {'(50000,20000)', '(50000,50000)'};
            app.redUAVs.Value = '(50000,20000)';
            app.Label_2 = uilabel(app.redSet);
            app.Label_2.Text = '红方策略';
            app.redSelect = uidropdown(app.redSet);
            app.redSelect.Items = {'策略一', '策略二', '策略三'};
            app.redSelect.ItemsData = {'1', '2', '3'};
            app.redSelect.Value = '1';
            app.addRedUAV = uibutton(app.redSet, 'push');
            app.addRedUAV.ButtonPushedFcn = createCallbackFcn(app, @addRedUAVButtonPushed, true);
            app.addRedUAV.Text = '添加无人机';
            app.delRedUAV = uibutton(app.redSet, 'push');
            app.delRedUAV.ButtonPushedFcn = createCallbackFcn(app, @delRedUAVButtonPushed, true);
            app.delRedUAV.Text = '删除选中';
            app.xLabel_2 = uilabel(app.redSet);
            app.xLabel_2.Text = 'x :';
            app.redXInput = uieditfield(app.redSet, 'numeric');
            app.redXInput.Limits = [0 Inf];
            app.redXInput.ValueDisplayFormat = '%.0f';
            app.yLabel_2 = uilabel(app.redSet);
            app.yLabel_2.Text = 'y :';
            app.redYInput = uieditfield(app.redSet, 'numeric');
            app.redYInput.Limits = [0 Inf];
            app.redYInput.ValueDisplayFormat = '%.0f';
            app.areaSet = uipanel(app.UIFigure);
            app.areaSet.Title = '区域设置';
            app.Label_3 = uilabel(app.areaSet);
            app.Label_3.Text = '攻击纵深';
            app.widthInput = uieditfield(app.areaSet, 'numeric');
            app.widthInput.Limits = [1 Inf];
            app.widthInput.ValueDisplayFormat = '%.0f';
            app.widthInput.ValueChangedFcn = createCallbackFcn(app, @AreaWidthValueChanged, true);
            app.widthInput.Value = 50000;
            app.Label_4 = uilabel(app.areaSet);
            app.Label_4.Text = '攻击通道';
            app.heightInput = uieditfield(app.areaSet, 'numeric');
            app.heightInput.Limits = [1 Inf];
            app.heightInput.ValueDisplayFormat = '%.0f';
            app.heightInput.ValueChangedFcn = createCallbackFcn(app, @AreaHeightValueChanged, true);
            app.heightInput.Value = 70000;
            app.Label_5 = uilabel(app.areaSet);
            app.Label_5.Text = '攻击间隔';
            app.girdInput = uieditfield(app.areaSet, 'numeric');
            app.girdInput.Limits = [0 Inf];
            app.girdInput.ValueDisplayFormat = '%.0f';
            app.girdInput.Value = 500;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '说明';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'蓝色方策略：'; '       策略一：直线飞行'; '       策略二：欺骗飞行：蓝色方无人机向两架拦截无人机中点靠近，当蓝色方无人机与任意一个红色方无人机的距离达到临界距离 ，判断两架红色方无人机到最近边界的竖直距离，选择距离较大的一侧，并攻击其边界点（C或D）。当蓝色方无人机超过红色方无人机水平位置时，蓝色方无人机采取直线飞行。'; '红色方策略：'; '       策略一：实时定位追踪：在每一时刻飞行之前，红色方无人机确定蓝色方无人机的位置，并对其进行追击。'; '       策略二：前置拦截：在每一时刻飞行之前，红色方无人机确定蓝色方无人机的位置，但并不主动追击，保持和蓝色方无人机在同一高度。'; '       策略三：综合追踪：在每一时刻飞行之前，红色方无人机确定蓝色方无人机的位置，一开始并不主动追击，保持和蓝色方无人机在同一高度，当双方距离为M时，红色方无人机采取主动追击（即策略一）。'; 'Ps：”攻击间隔“仅影响对抗区域仿真，不影响策略仿真。'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end